classdef Reporting_assignment_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        sharpenButton     matlab.ui.control.Button
        blurButton        matlab.ui.control.Button
        histButton        matlab.ui.control.Button
        edgeButton        matlab.ui.control.Button
        hsvButton         matlab.ui.control.Button
        labButton         matlab.ui.control.Button
        rgbButton         matlab.ui.control.Button
        compressButton    matlab.ui.control.Button
        fourierButton     matlab.ui.control.Button
        sigmaSlider       matlab.ui.control.Slider
        sigmaSliderLabel  matlab.ui.control.Label
        retinexButton     matlab.ui.control.Button
        computeButton     matlab.ui.control.Button
        loadButton        matlab.ui.control.Button
        UIAxes13          matlab.ui.control.UIAxes
        UIAxes12          matlab.ui.control.UIAxes
        UIAxes11          matlab.ui.control.UIAxes
        UIAxes10          matlab.ui.control.UIAxes
        UIAxes9           matlab.ui.control.UIAxes
        UIAxes8           matlab.ui.control.UIAxes
        UIAxes7           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
        UIAxes6           matlab.ui.control.UIAxes
        UIAxes5           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im % Description
        sigma = 1
        num_colors = 8 % Default number of colors for compression
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename = uigetfile('*.png');
            % read image
            app.im=imread(filename);
            % % plot image
            imshow(app.im);
            imshow(app.im,'Parent',app.UIAxes);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end
            
            % compute image
            imgray = rgb2gray(app.im);
            BW = imbinarize(imgray);
            % plot images
            imshow(BW,'Parent',app.UIAxes2);
        end

        % Value changed function: sigmaSlider
        function sigmaSliderValueChanged(app, event)
            app.sigma = app.sigmaSlider.Value;
            app.retinexButtonPushed(event);
        end

        % Button pushed function: retinexButton
        function retinexButtonPushed(app, event)
            
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end
            
            % 入力画像の読み込み
            img = im2double(app.im);
                        
            % RGB各チャンネルに対してレティネックス処理を適用
            [rows, cols, channels] = size(img);
            retinexImage = zeros(rows, cols, channels);
            
            for c = 1:channels
                % ガウシアンフィルターで照明推定
                illumination = imgaussfilt(img(:,:,c), app.sigma);
                
                % レティネックスの計算
                retinexImage(:,:,c) = log(img(:,:,c) + 1) - log(illumination + 1);
            end
            
            % 出力画像のスケーリング
            retinexImage = retinexImage - min(retinexImage(:));
            retinexImage = retinexImage / max(retinexImage(:));

            imshow(retinexImage,'Parent',app.UIAxes3);
        end

        % Button pushed function: fourierButton
        function fourierButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Convert to grayscale
            imgray = rgb2gray(app.im);
            % Perform Fourier Transform
            F = fft2(imgray);
            Fshift = fftshift(F); % Shift zero frequency to center
            Flog = log(1 + abs(Fshift)); % Log transform for better visualization

            % Normalize to [0, 1]
            Fnorm = mat2gray(Flog);

            % Display the result
            imshow(Fnorm, 'Parent', app.UIAxes4);
        end

        % Button pushed function: compressButton
        function compressButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Convert image to double precision for K-means
            im = im2double(app.im);
            % Reshape the image into an Nx3 matrix where N is the number of pixels
            pixelData = reshape(im, [], 3);
            
            % Apply K-means clustering
            [idx, C] = kmeans(pixelData, app.num_colors, 'MaxIter', 200);
            
            % Reconstruct the compressed image
            compressed_im = reshape(C(idx, :), size(im));
            
            % Display the compressed image
            imshow(compressed_im, 'Parent', app.UIAxes5);            % Display the color palette
            palette = reshape(C, [1, app.num_colors, 3]);
            imshow(palette, 'Parent', app.UIAxes6);
        end

        % Button pushed function: rgbButton
        function rgbButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Get the pixel data
            im = im2double(app.im);
            pixelData = reshape(im, [], 3);

            % Plot the RGB color space
            scatter3(app.UIAxes7, pixelData(:,1), pixelData(:,2), pixelData(:,3), 1, pixelData, 'filled');
            xlabel(app.UIAxes7, 'Red');
            ylabel(app.UIAxes7, 'Green');
            zlabel(app.UIAxes7, 'Blue');
            title(app.UIAxes7, 'RGB Color Space');
        end

        % Button pushed function: labButton
        function labButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Convert the image to LAB color space
            im_lab = rgb2lab(app.im);
            pixelData = reshape(im_lab, [], 3);

            % Plot the LAB color space
            scatter3(app.UIAxes8, pixelData(:,1), pixelData(:,2), pixelData(:,3), 1, pixelData, 'filled');
            xlabel(app.UIAxes8, 'L*');
            ylabel(app.UIAxes8, 'a*');
            zlabel(app.UIAxes8, 'b*');
        end

        % Button pushed function: hsvButton
        function hsvButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Convert the image to HSV color space
            im_hsv = rgb2hsv(app.im);
            pixelData = reshape(im_hsv, [], 3);

            % Plot the HSV color space
            scatter3(app.UIAxes9, pixelData(:,1), pixelData(:,2), pixelData(:,3), 1, pixelData, 'filled');
            xlabel(app.UIAxes9, 'Hue');
            ylabel(app.UIAxes9, 'Saturation');
            zlabel(app.UIAxes9, 'Value');
        end

        % Button pushed function: edgeButton
        function edgeButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Convert to grayscale
            imgray = rgb2gray(app.im);
            % Perform edge detection using Canny method
            edges = edge(imgray, 'Canny');

            % Display the edges
            imshow(edges, 'Parent', app.UIAxes10);
        end

        % Button pushed function: histButton
        function histButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Convert to grayscale
            imgray = rgb2gray(app.im);
            % Perform histogram equalization
            hist_eq_img = histeq(imgray);

            % Display the result
            imshow(hist_eq_img, 'Parent', app.UIAxes11);
        end

        % Button pushed function: blurButton
        function blurButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Apply Gaussian blur
            blurred_img = imgaussfilt(app.im, 2);

            % Display the result
            imshow(blurred_img, 'Parent', app.UIAxes12);
        end

        % Button pushed function: sharpenButton
        function sharpenButtonPushed(app, event)
            if isempty(app.im)
                uialert(app.UIFigure, 'Image not loaded.', 'Error');
                return;
            end

            % Apply sharpening
            sharpened_img = imsharpen(app.im);

            % Display the result
            imshow(sharpened_img, 'Parent', app.UIAxes13);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Binarization')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Retinex')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Fourier')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Compressed')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Color palette')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'RGB Color Space')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'LAB Color Space')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'HSV Color Space')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, 'Edge detection')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.UIFigure);
            title(app.UIAxes11, 'Histogram')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.UIFigure);
            title(app.UIAxes12, 'Gaussian')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes13 = uiaxes(app.UIFigure);
            title(app.UIAxes13, 'Sharpening')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.retinexButton = uibutton(app.UIFigure, 'push');
            app.retinexButton.ButtonPushedFcn = createCallbackFcn(app, @retinexButtonPushed, true);
            app.retinexButton.Text = 'retinex';
            app.sigmaSliderLabel = uilabel(app.UIFigure);
            app.sigmaSliderLabel.Text = 'sigma [%]';
            app.sigmaSlider = uislider(app.UIFigure);
            app.sigmaSlider.Limits = [1 100];
            app.sigmaSlider.MajorTicks = [1 20 40 60 80 100];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged, true);
            app.sigmaSlider.Value = 10;
            app.fourierButton = uibutton(app.UIFigure, 'push');
            app.fourierButton.ButtonPushedFcn = createCallbackFcn(app, @fourierButtonPushed, true);
            app.fourierButton.Text = 'fourier';
            app.compressButton = uibutton(app.UIFigure, 'push');
            app.compressButton.ButtonPushedFcn = createCallbackFcn(app, @compressButtonPushed, true);
            app.compressButton.Text = 'compress';
            app.rgbButton = uibutton(app.UIFigure, 'push');
            app.rgbButton.ButtonPushedFcn = createCallbackFcn(app, @rgbButtonPushed, true);
            app.rgbButton.Text = 'rgb';
            app.labButton = uibutton(app.UIFigure, 'push');
            app.labButton.ButtonPushedFcn = createCallbackFcn(app, @labButtonPushed, true);
            app.labButton.Text = 'lab';
            app.hsvButton = uibutton(app.UIFigure, 'push');
            app.hsvButton.ButtonPushedFcn = createCallbackFcn(app, @hsvButtonPushed, true);
            app.hsvButton.Text = 'hsv';
            app.edgeButton = uibutton(app.UIFigure, 'push');
            app.edgeButton.ButtonPushedFcn = createCallbackFcn(app, @edgeButtonPushed, true);
            app.edgeButton.Text = 'edge';
            app.histButton = uibutton(app.UIFigure, 'push');
            app.histButton.ButtonPushedFcn = createCallbackFcn(app, @histButtonPushed, true);
            app.histButton.Text = 'hist';
            app.blurButton = uibutton(app.UIFigure, 'push');
            app.blurButton.ButtonPushedFcn = createCallbackFcn(app, @blurButtonPushed, true);
            app.blurButton.Text = 'blur';
            app.sharpenButton = uibutton(app.UIFigure, 'push');
            app.sharpenButton.ButtonPushedFcn = createCallbackFcn(app, @sharpenButtonPushed, true);
            app.sharpenButton.Text = 'sharpen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reporting_assignment_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end